classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DONELabel                matlab.ui.control.Label
        LoadButton               matlab.ui.control.Button
        MalignantLabel           matlab.ui.control.Label
        BenignLabel              matlab.ui.control.Label
        STAGE2Label              matlab.ui.control.Label
        STAGE1Label              matlab.ui.control.Label
        PBDCTButton_3            matlab.ui.control.Button
        HOTButton_3              matlab.ui.control.Button
        PBDCTButton_2            matlab.ui.control.Button
        HOTButton_2              matlab.ui.control.Button
        PBDCTButton              matlab.ui.control.Button
        FeatureExtractionButton  matlab.ui.control.Button
        HOTButton                matlab.ui.control.Button
        PreprocessingButton      matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
            app.DONELabel.Text = 'Preprocessing...';
            drawnow
            preprocess('../Data/dataset for experiments/');
            app.DONELabel.Text = 'DONE';
            drawnow
        end

        % Button pushed function: FeatureExtractionButton
        function FeatureExtractionButtonPushed(app, event)
            app.DONELabel.Text = 'Extracting...';
            drawnow
            features('../Data/datapre/');
            app.DONELabel.Text = 'DONE';
            drawnow
        end

        % Button pushed function: HOTButton
        function HOTButtonPushed(app, event)
            app.DONELabel.Text = 'Classifying(S1 HOT)...';
            drawnow
            
            [~,~,raw] = xlsread('../Data/labels.xlsx','A1:C480');
            map = containers.Map(raw(:,1),raw(:,2));
            
            [r,~] = size(app.data.names);
            out = [];
            for i = 1:r
                out = [map(string(app.data.names(i)));out];
            end
            
            in = app.data.HOG_featurevectors(:,:);
            
            %sensitivity,specificity,precision,FPR,Accuracy,recall,F1
            [ res ] = helper(in, out, 50);
    
            %PLOT
            hold on
            plot(res(:,1),res(:,2)*100,':b', 'LineWidth',2)
            plot(res(:,1),res(:,3)*100,':g', 'LineWidth',2)
            plot(res(:,1),res(:,6)*100,':r', 'LineWidth',2)
            

            grid on

            title('STAGE-1 HOT')
            xlabel('Number of Features')
            ylabel( 'Percentage(%)');

            legend('Sensitivity','Specificity','Accuracy',  'Location','best')
           
            app.DONELabel.Text = 'DONE';
            drawnow
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.DONELabel.Text = 'Loading...';
            drawnow
            app.data = jsondecode(fileread('features.json'));jsondecode(fileread('features.json'));
            app.DONELabel.Text = 'DONE';
            drawnow
        end

        % Button pushed function: PBDCTButton
        function PBDCTButtonPushed(app, event)
            app.DONELabel.Text = 'Classifying(S1 PB-DCT)...';
            drawnow
            
            [~,~,raw] = xlsread('../Data/labels.xlsx','A1:C480');
            map = containers.Map(raw(:,1),raw(:,2));
            
            [r,~] = size(app.data.names);
            out = [];
            for i = 1:r
                out = [map(string(app.data.names(i)));out];
            end
            
            out = [];
            for i = 1:r
                out = [map(string(app.data.names(i)));out];
            end
            
            in = app.data.DCT_featurevectors(:,:);
            %sensitivity,specificity,precision,FPR,Accuracy,recall,F1
            [ res ] = helper(in, out, 50);
            
            %PLOT

            hold on
            plot(res(:,1),res(:,2)*100,':b', 'LineWidth',2)
            plot(res(:,1),res(:,3)*100,':g', 'LineWidth',2)
            plot(res(:,1),res(:,6)*100,':r', 'LineWidth',2)
            

            grid on

            title('STAGE-1 PB-DCT')
            xlabel('Number of Features')
            ylabel( 'Percentage(%)');

            legend('Sensitivity', 'Specificity','Accuracy',  'Location','best')


            app.DONELabel.Text = 'DONE';
            drawnow
        end

        % Button pushed function: HOTButton_2
        function HOTButton_2Pushed(app, event)
            app.DONELabel.Text = 'CLassifying(S2 B HOT)...';
            drawnow
            
            [num,~,raw] = xlsread('../Data/labels1.xlsx','A1:C480');
            map = containers.Map(raw(:,1),[1:480]);
            
            [r,~] = size(app.data.names);
            ben = [];
            mal = [];
            
            for i = 1:r
                idx = map(string(app.data.names(i)));
                if num(idx,1) == 1
                   ben = [ben;i,idx];
                elseif num(idx,1) == 2
                   mal = [mal;i,idx];
                end
            end
            
             benout = [];
            [r,~] = size(ben);
            for i = 1:r
                benout = [benout;num(ben(i,2),2)-1];
            end
            
            databen = app.data.HOG_featurevectors(ben(:,1),:);
            %sensitivity,specificity,precision,FPR,Accuracy,recall,F1
            res = helper(databen,benout, 50);
         
            %PLOT

            hold on
            plot(res(:,1),res(:,2)*100,':b', 'LineWidth',2)
            plot(res(:,1),res(:,3)*100,':g', 'LineWidth',2)
            plot(res(:,1),res(:,6)*100,':r', 'LineWidth',2)
            

            grid on

            title('STAGE-2 Benign HOT')
            xlabel('Number of Features')
            ylabel( 'Percentage(%)');

            legend('Sensitivity','Specificity','Accuracy',  'Location','best')


            app.DONELabel.Text = 'DONE';
            drawnow
        end

        % Button pushed function: PBDCTButton_2
        function PBDCTButton_2Pushed(app, event)
            app.DONELabel.Text = 'CLassifying(S2 B PB-DCT)...';
            drawnow
            
            [num,~,raw] = xlsread('../Data/labels1.xlsx','A1:C480');
            map = containers.Map(raw(:,1),[1:480]);
            
            [r,~] = size(app.data.names);
            ben = [];
            mal = [];
            
            for i = 1:r
                idx = map(string(app.data.names(i)));
                if num(idx,1) == 1
                   ben = [ben;i,idx];
                elseif num(idx,1) == 2
                   mal = [mal;i,idx];
                end
            end
            
            benout = [];
            [r,~] = size(ben);
            for i = 1:r
                benout = [benout;num(ben(i,2),2)-1];
            end
            
            databen = app.data.DCT_featurevectors(ben(:,1),:);
            %sensitivity,specificity,precision,FPR,Accuracy,recall,F1
            res = helper(databen,benout, 50);
            
            %PLOT

            hold on
            plot(res(:,1),res(:,2)*100,':b', 'LineWidth',2)
            plot(res(:,1),res(:,3)*100,':g', 'LineWidth',2)
            plot(res(:,1),res(:,6)*100,':r', 'LineWidth',2)
            

            grid on

            title('STAGE-2 Benign PB-DCT')
            xlabel('Number of Features')
            ylabel( 'Percentage(%)');

            legend('Sensitivity','Specificity','Accuracy',  'Location','best')


            app.DONELabel.Text = 'DONE';
            drawnow
        end

        % Button pushed function: HOTButton_3
        function HOTButton_3Pushed(app, event)
            app.DONELabel.Text = 'CLassifying(S2 M HOT)...';
            drawnow
            
            [num,~,raw] = xlsread('../Data/labels1.xlsx','A1:C480');
            map = containers.Map(raw(:,1),[1:480]);
            
            [r,~] = size(app.data.names);
            ben = [];
            mal = [];
            
            for i = 1:r
                idx = map(string(app.data.names(i)));
                if num(idx,1) == 1
                   ben = [ben;i,idx];
                elseif num(idx,1) == 2
                   mal = [mal;i,idx];
                end
            end
            
            malout = [];
            [r,~] = size(mal);
            for i = 1:r
                malout = [malout;num(mal(i,2),2)-3];
            end
    
            datamal = app.data.HOG_featurevectors(mal(:,1),:);
           
            %sensitivity,specificity,precision,FPR,Accuracy,recall,F1
            [res] = helper(datamal, malout, 50);
            
            %PLOT

            hold on
            plot(res(:,1),res(:,2)*100,':b', 'LineWidth',2)
            plot(res(:,1),res(:,3)*100,':g', 'LineWidth',2)
            plot(res(:,1),res(:,6)*100,':r', 'LineWidth',2)
            

            grid on

            title('STAGE-2 Malignant HOT')
            xlabel('Number of Features')
            ylabel( 'Percentage(%)');

            legend('Sensitivity','Specificity','Accuracy',  'Location','best')


            app.DONELabel.Text = 'DONE';
            drawnow
        end

        % Button pushed function: PBDCTButton_3
        function PBDCTButton_3Pushed(app, event)
            app.DONELabel.Text = 'CLassifying(S2 M HOT)...';
            drawnow
            
            [num,~,raw] = xlsread('../Data/labels1.xlsx','A1:C480');
            map = containers.Map(raw(:,1),[1:480]);
            
            [r,~] = size(app.data.names);
            ben = [];
            mal = [];
            
            for i = 1:r
                idx = map(string(app.data.names(i)));
                if num(idx,1) == 1
                   ben = [ben;i,idx];
                elseif num(idx,1) == 2
                   mal = [mal;i,idx];
                end
            end
            
            malout = [];
            [r,~] = size(mal);
            for i = 1:r
                malout = [malout;num(mal(i,2),2)-3];
            end
            
            datamal = app.data.DCT_featurevectors(mal(:,1), :);
            %sensitivity,specificity,precision,FPR,Accuracy,recall,F1
            [res] = helper(datamal,malout, 50);
            
            %PLOT

            hold on
            plot(res(:,1),res(:,2)*100,':b', 'LineWidth',2)
            plot(res(:,1),res(:,3)*100,':g', 'LineWidth',2)
            plot(res(:,1),res(:,6)*100,':r', 'LineWidth',2)
            

            grid on

            title('STAGE-2 Malignant PB-DCT')
            xlabel('Number of Features')
            ylabel( 'Percentage(%)');

            legend('Sensitivity','Specificity','Accuracy',  'Location','best')


            app.DONELabel.Text = 'DONE';
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Features')
            ylabel(app.UIAxes, 'Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.PreprocessingButton = uibutton(app.UIFigure, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Text = 'Preprocessing';
            app.HOTButton = uibutton(app.UIFigure, 'push');
            app.HOTButton.ButtonPushedFcn = createCallbackFcn(app, @HOTButtonPushed, true);
            app.HOTButton.Text = 'HOT';
            app.FeatureExtractionButton = uibutton(app.UIFigure, 'push');
            app.FeatureExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureExtractionButtonPushed, true);
            app.FeatureExtractionButton.Text = 'Feature Extraction';
            app.PBDCTButton = uibutton(app.UIFigure, 'push');
            app.PBDCTButton.ButtonPushedFcn = createCallbackFcn(app, @PBDCTButtonPushed, true);
            app.PBDCTButton.Text = 'PB-DCT';
            app.HOTButton_2 = uibutton(app.UIFigure, 'push');
            app.HOTButton_2.ButtonPushedFcn = createCallbackFcn(app, @HOTButton_2Pushed, true);
            app.HOTButton_2.Text = 'HOT';
            app.PBDCTButton_2 = uibutton(app.UIFigure, 'push');
            app.PBDCTButton_2.ButtonPushedFcn = createCallbackFcn(app, @PBDCTButton_2Pushed, true);
            app.PBDCTButton_2.Text = 'PB-DCT';
            app.HOTButton_3 = uibutton(app.UIFigure, 'push');
            app.HOTButton_3.ButtonPushedFcn = createCallbackFcn(app, @HOTButton_3Pushed, true);
            app.HOTButton_3.Text = 'HOT';
            app.PBDCTButton_3 = uibutton(app.UIFigure, 'push');
            app.PBDCTButton_3.ButtonPushedFcn = createCallbackFcn(app, @PBDCTButton_3Pushed, true);
            app.PBDCTButton_3.Text = 'PB-DCT';
            app.STAGE1Label = uilabel(app.UIFigure);
            app.STAGE1Label.Text = 'STAGE 1';
            app.STAGE2Label = uilabel(app.UIFigure);
            app.STAGE2Label.Text = 'STAGE 2';
            app.BenignLabel = uilabel(app.UIFigure);
            app.BenignLabel.Text = 'Benign';
            app.MalignantLabel = uilabel(app.UIFigure);
            app.MalignantLabel.Text = 'Malignant';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DONELabel = uilabel(app.UIFigure);
            app.DONELabel.Text = 'DONE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end